classdef app1 < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        TabGroup                   matlab.ui.container.TabGroup
        Tab                        matlab.ui.container.Tab
        UITable                    matlab.ui.control.Table
        TextArea                   matlab.ui.control.TextArea
        CompButton                 matlab.ui.control.Button
        TW2EditField               matlab.ui.control.NumericEditField
        TW2EditFieldLabel          matlab.ui.control.Label
        TW1EditField               matlab.ui.control.NumericEditField
        TW1EditFieldLabel          matlab.ui.control.Label
        PlotButton                 matlab.ui.control.Button
        ProjectPathEditField       matlab.ui.control.EditField
        ProjectPathEditFieldLabel  matlab.ui.control.Label
        IV2DropDown                matlab.ui.control.DropDown
        IV2DropDownLabel           matlab.ui.control.Label
        IV1DropDown                matlab.ui.control.DropDown
        IV1DropDownLabel           matlab.ui.control.Label
        DVDropDown                 matlab.ui.control.DropDown
        DVDropDownLabel            matlab.ui.control.Label
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
        Tab2                       matlab.ui.container.Tab
    end

    properties (Access = public)
        DV = '';
        Data
        IV1 = '';
        IV2 = '';
        ivVal = [];
        projectDir = 'D:\EmoStroop\';
        tw = [];
        tw1
        tw2
        varName % available variablse
    end
    
    
    methods (Access = private)
        
        function ShowTable(comp, T)
            if isempty(comp.TableHandle) || ~isvalid(comp.TableHandle)
                comp.TableHandle = uitable(comp.UIAxes, ...      % 父物件是 app.UIFigure
                    'Data', T, ...
                    'Position', [20 20 400 200]);                % 位置自行決定
            else
                comp.TableHandle.Data = T;                        % 更新資料
            end
        end

        
function updateDisplay(comp, Data)
% Data 可以是 numeric、cell array、或 table
% 規則：偵測為「連續」→ plot，否則 → UITable

    % ── 1. 判斷是否為「連續資料」 ──
    isContinuous = false;

    if isnumeric(Data)                      % 數值向量 / 矩陣
        isContinuous = true;

    elseif istable(Data)
        % 例：只有數值欄，且列數不少；可依需求再細分
        numCols = varfun(@(x) size(x,2), Data, "OutputFormat", "uniform");
        if any(numCols > 10)
            isContinuous = true;
        end
    end

    % ── 2. 根據判斷執行 ──
    if isContinuous
        % 顯示 UIAxes、隱藏 UITable
        comp.UIAxes.Visible = 'on';
        if isprop(comp, 'UITable');  comp.UITable.Visible = 'off';  end

        cla(comp.UIAxes);  hold(comp.UIAxes, 'on');

        % 畫圖：根據 Data 形態自動處理
        if isnumeric(Data)
            plotDynamic_app(comp.UIAxes, comp.Data, ...
                'DV', comp.DV, ...
                'BT', comp.IV1, ...
                'WT', comp.IV2, ...
                'tw', [-1000, 3999, -1000], ...
                'col', {[0 .5 .8];[.8 .1 0];[0 .5 .8];[.8 .1 0]}, ...
                'lnstyle', {'-', '-', '--', '--'});
        elseif istable(Data)
            plotDynamic_app(comp.UIAxes, comp.Data, ...
                'DV', comp.DV, ...
                'BT', comp.IV1, ...
                'WT', comp.IV2, ...
                'tw', [-1000, 3999, -1000], ...
                'col', {[0 .5 .8];[.8 .1 0];[0 .5 .8];[.8 .1 0]}, ...
                'lnstyle', {'-', '-', '--', '--'});
        end

    else
        % 顯示 UITable、隱藏 UIAxes
        if isprop(comp, 'UITable')
            comp.UITable.Visible = 'on';
            comp.UITable.Data    = Data;
        else
            % 動態產生 (若畫面上沒拖 UITable)
            if isempty(comp.TableHandle) || ~isvalid(comp.TableHandle)
                comp.TableHandle = uitable(comp.UIFigure, ...
                    'Position', [20 20 400 200]);
            end
            comp.TableHandle.Data = Data;
            comp.TableHandle.Visible = 'on';
        end
        comp.UIAxes.Visible = 'off';
    end
end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DVDropDown
        function DVDropDownValueChanged(comp, event)
            comp.DV = comp.DVDropDown.Value;
            
        end

        % Clicked callback: DVDropDown
        function DVDropDownClicked(comp, event)
            item = event.InteractionInformation.Item;
            
        end

        % Value changed function: ProjectPathEditField
        function ProjectPathEditFieldValueChanged(comp, event)
            comp.projectDir = comp.ProjectPathEditField.Value;
            comp.varName = load([comp.projectDir '\derivatives\prec\VarName.mat']).VarName;
            comp.DVDropDown.Items = comp.varName;
            comp.IV1DropDown.Items = comp.varName;
            comp.IV2DropDown.Items = comp.varName;
            comp.TextArea.Value = comp.projectDir;
        end

        % Value changed function: IV1DropDown
        function IV1DropDownValueChanged(comp, event)
            comp.IV1 = comp.IV1DropDown.Value;
            
        end

        % Value changed function: IV2DropDown
        function IV2DropDownValueChanged(comp, event)
            comp.IV2 = comp.IV2DropDown.Value;
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(comp, event)
            IV = {comp.IV1,comp.IV2};
            comp.Data = getValues(comp.projectDir, ...
                                  comp.DV, ...
                                  comp.tw, ...
                                  IV,...
                                  comp.ivVal);

            cla(comp.UIAxes)
            updateDisplay(comp,comp.Data)
            % plotDynamic_app(comp.UIAxes, comp.Data, ...
            %     'DV', comp.DV, ...
            %     'BT', comp.IV1, ...
            %     'WT', comp.IV2, ...
            %     'tw', [-100, 3999, -1000], ...
            %     'col', {[0 .5 .8];[.8 .1 0];[0 .5 .8];[.8 .1 0]}, ...
            %     'lnstyle', {'-', '-', '--', '--'}, ...
            %     'xlab', 'Time (ms)', ...
            %     'ylab', 'Pupil Size');
        end

        % Value changed function: TW1EditField
        function TW1EditFieldValueChanged(comp, event)
            comp.tw1 = comp.TW1EditField.Value;
            
        end

        % Value changed function: TW2EditField
        function TW2EditFieldValueChanged(comp, event)
            comp.tw2 = comp.TW2EditField.Value;
            
        end

        % Button pushed function: CompButton
        function CompButtonPushed(comp, event)
            IV = {comp.IV1,comp.IV2};
            comp.Data = getValues(comp.projectDir, ...
                comp.DV, ...
                [comp.tw1 comp.tw2], ...
                IV, ...
                comp.ivVal);

            cla(comp.UIAxes2)
            plotBox_app(comp.UIAxes2, comp.Data, ...
                'DV', comp.DV, ...
                'BT', comp.IV1, ...
                'WT', comp.IV2, ...
                'col', {[0 .5 .8];[.8 .1 0];[0 .5 .8];[.8 .1 0]}, ...
                'dot', true, ...
                'xlab', 'Condition', ...
                'ylab', 'Pupil Size');

            [~, f] = mixed_anova(comp.Data,comp.DV,comp.IV1,comp.IV2);
            comp.TextArea.Value = f;
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Use this function to update the underlying component

        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 615 552];
            comp.TabGroup = uitabgroup(comp);
            comp.Tab = uitab(comp.TabGroup);
            comp.Tab.Title = 'Tab';
            comp.UIAxes = uiaxes(comp.Tab);
            comp.UIAxes2 = uiaxes(comp.Tab);
            comp.DVDropDownLabel = uilabel(comp.Tab);
            comp.DVDropDownLabel.Text = 'DV';
            comp.DVDropDown = uidropdown(comp.Tab);
            comp.DVDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @DVDropDownValueChanged, true);
            comp.DVDropDown.ClickedFcn = matlab.apps.createCallbackFcn(comp, @DVDropDownClicked, true);
            comp.IV1DropDownLabel = uilabel(comp.Tab);
            comp.IV1DropDownLabel.Text = 'IV1';
            comp.IV1DropDown = uidropdown(comp.Tab);
            comp.IV1DropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @IV1DropDownValueChanged, true);
            comp.IV2DropDownLabel = uilabel(comp.Tab);
            comp.IV2DropDownLabel.Text = 'IV2';
            comp.IV2DropDown = uidropdown(comp.Tab);
            comp.IV2DropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @IV2DropDownValueChanged, true);
            comp.ProjectPathEditFieldLabel = uilabel(comp.Tab);
            comp.ProjectPathEditFieldLabel.Text = 'Project Path';
            comp.ProjectPathEditField = uieditfield(comp.Tab, 'text');
            comp.ProjectPathEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @ProjectPathEditFieldValueChanged, true);
            comp.ProjectPathEditField.Value = 'D:\EmoStroop\';
            comp.PlotButton = uibutton(comp.Tab, 'push');
            comp.PlotButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @PlotButtonPushed, true);
            comp.PlotButton.Text = 'Plot';
            comp.TW1EditFieldLabel = uilabel(comp.Tab);
            comp.TW1EditFieldLabel.Text = 'TW1';
            comp.TW1EditField = uieditfield(comp.Tab, 'numeric');
            comp.TW1EditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TW1EditFieldValueChanged, true);
            comp.TW2EditFieldLabel = uilabel(comp.Tab);
            comp.TW2EditFieldLabel.Text = 'TW2';
            comp.TW2EditField = uieditfield(comp.Tab, 'numeric');
            comp.TW2EditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TW2EditFieldValueChanged, true);
            comp.CompButton = uibutton(comp.Tab, 'push');
            comp.CompButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @CompButtonPushed, true);
            comp.CompButton.Text = 'Comp';
            comp.TextArea = uitextarea(comp.Tab);
            comp.UITable = uitable(comp.Tab);
            comp.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            comp.UITable.RowName = {};
            comp.Tab2 = uitab(comp.TabGroup);
            comp.Tab2.Title = 'Tab2';
        end
    end
end